classdef data_analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        MergeDataTab            matlab.ui.container.Tab
        GridLayout              matlab.ui.container.GridLayout
        GridLayout2             matlab.ui.container.GridLayout
        AnimalSessionsLabel     matlab.ui.control.Label
        DataList                matlab.ui.container.Tree
        GridLayout3             matlab.ui.container.GridLayout
        LoadDataButton          matlab.ui.control.Button
        SettingsButton          matlab.ui.control.Button
        MergeButton             matlab.ui.control.Button
        SelectButton            matlab.ui.control.Button
        OtherInformationButton  matlab.ui.control.Button
        GridLayout4             matlab.ui.container.GridLayout
        IncludeDataLabel        matlab.ui.control.Label
        GridLayout5             matlab.ui.container.GridLayout
        EventsCheckBox          matlab.ui.control.CheckBox
        OnlineSpikesCheckBox    matlab.ui.control.CheckBox
        OfflineSpikesCheckBox   matlab.ui.control.CheckBox
        AnalogDataCheckBox      matlab.ui.control.CheckBox
        VideoCheckBox           matlab.ui.control.CheckBox
        ExistInfoLabel          matlab.ui.control.Label
        HelpLabel               matlab.ui.control.Label
        IsSelectedLabel         matlab.ui.control.Label
        SUMUCheckBox            matlab.ui.control.CheckBox
        PlotFiguresTab          matlab.ui.container.Tab
        GridLayout6             matlab.ui.container.GridLayout
        GridLayout7             matlab.ui.container.GridLayout
        AnimalSessionsLabel_2   matlab.ui.control.Label
        DataList_2              matlab.ui.container.Tree
        GridLayout8             matlab.ui.container.GridLayout
        SingleSessionPlotLabel  matlab.ui.control.Label
        MultiSessionsPlot1animalpertimeLabel  matlab.ui.control.Label
        SinglePlots             matlab.ui.control.ListBox
        MultiPlots              matlab.ui.control.ListBox
        GridLayout9             matlab.ui.container.GridLayout
        LoadDataButton_2        matlab.ui.control.Button
        SelectSessionButton     matlab.ui.control.Button
        SelectSinglePlotButton  matlab.ui.control.Button
        SelectMultiPlotButton   matlab.ui.control.Button
        PlotButton              matlab.ui.control.Button
        LoadFuncButton          matlab.ui.control.Button
        SettingsButton_2        matlab.ui.control.Button
        SessionInfoLabel        matlab.ui.control.Label
        VideoClippingTab        matlab.ui.container.Tab
        GridLayout10            matlab.ui.container.GridLayout
        GridLayout11            matlab.ui.container.GridLayout
        AnimalSessionsLabel_3   matlab.ui.control.Label
        DataList_3              matlab.ui.container.Tree
        GridLayout12            matlab.ui.container.GridLayout
        BlackrockFrames         matlab.ui.control.UIAxes
        RGBofLED                matlab.ui.control.UIAxes
        GridLayout13            matlab.ui.container.GridLayout
        VideoInfoLabel          matlab.ui.control.Label
        LoadDataButton_3        matlab.ui.control.Button
        AnalysisButton          matlab.ui.control.Button
        RunButton               matlab.ui.control.Button
        SelectVideoButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        DataPath
        
        AnimalNodes={}; %{anm1,anm2,...}
        SessionNodes={}; %{{sess1,sess2,...},{sess1,sess2,..},...}
        IsSelected={}; %{{animal1},{{session1},{session2}}}
        CheckBoxStates={}; %{{animal1},{{session1},{session2},{event,online,offline,SU/MU,analog,video,'111010'(which is already included)}}}
        ContainsRawData={};
        
        
        IncludeDataCheckBoxs={}; %contains all checkboxs within "include data"
        
        CurrentNode=[];
        
        SessionSelected={}; %{{animal1},{{session1},{session2}}}
        SinglePlotSelected=[]; %{'asdfa','asdfads',...}
        MultiPlotSelected=[]; %{'asdfa','asdfads',...}
        
        OtherInformation_app
        OtherInformation={};
        
        VideoSelected={};
    end
    
    properties (Access = public)
        
    end
    
    methods (Access = private)
        
        function plot_blackrock_frame_time_diff(app,data)
        end
        
        function CheckBoxInfo=get_rawdata_info(~,node)
            CheckBoxInfo=[];
            datapath=node.NodeData;
            t=dir(datapath);
            flag=[0,0,0,0,0,0];
            flag2=[2,1,1,1,1,2];
            includes='';
            for i=3:size(t,1)
                if contains(t(i).name,'BODATA');  includes=t(i).name(end-9:end-4); end
                %                 if contains(t(i).name,'nev');     flag(1)=flag(1)+1;               end
                if contains(t(i).name,'ST');      flag(1)=flag(1)+1;               end
                %                 if contains(t(i).name,'ns2');     flag(2)=flag(2)+1;               end
                if contains(t(i).name,'times_chdat');  flag(3)=flag(3)+1;          end
                if contains(t(i).name,'spike_config'); flag(4)=flag(4)+1;          end
                %                 if contains(t(i).name,'ns4');     flag(5)=flag(5)+1;               end
                if contains(t(i).name,'Record Node 101');     flag(1)=flag(1)+1;   end
                if contains(t(i).name,'Record Node 101');     flag(5)=flag(5)+1;   end
                if contains(t(i).name,'Record Node 128');     flag(2)=flag(2)+1;   end
                if contains(t(i).name,'Cam');     flag(6)=flag(6)+1;               end
            end
            
            for i=1:6
                if flag(i)>=flag2(i);     CheckBoxInfo{end+1}=1;
                else;                     CheckBoxInfo{end+1}=0; end
            end
            
            if isempty(includes);         CheckBoxInfo{end+1}='n';
            else;                         CheckBoxInfo{end+1}=includes; end
        end
        
        
        function clear_all(app)
            t=app.DataList_3.Children;
            for i=1:length(t);   delete(t(i));  end
            t=app.DataList_2.Children;
            for i=1:length(t);   delete(t(i));  end
            t=app.DataList.Children;
            for i=1:length(t);   delete(t(i));  end
            app.SinglePlots.Items={};
            app.MultiPlots.Items={};
            app.AnimalNodes={};
            app.SessionNodes={};
            app.SessionSelected={};
            app.SinglePlotSelected=[];
            app.MultiPlotSelected=[];
            app.SinglePlots.Items={};
            app.MultiPlots.Items={};
            app.CheckBoxStates={};
            app.ContainsRawData={};
            app.CurrentNode=[];
            app.IsSelected={};
            app.IsSelectedLabel.Text='';
            app.OtherInformation={};
            app.VideoSelected={};
        end
    end
    
    methods (Access = public)
        
        
        function update_OtherInformation(app,infor)
            for iiiii=1:length(app.IsSelected)
                for jjjjj=1:length(app.IsSelected{iiiii})
                    if app.IsSelected{iiiii}{jjjjj}==1
                        app.OtherInformation{iiiii}{jjjjj}=infor;
                    end
                end
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DataList_2.Multiselect=0;
            app.DataList.Multiselect=0;
            app.DataPath='G:\lab\volitional-BCI\Data';
            app.IncludeDataCheckBoxs={app.EventsCheckBox,app.OnlineSpikesCheckBox,app.OfflineSpikesCheckBox,app.SUMUCheckBox,app.AnalogDataCheckBox,app.VideoCheckBox};
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            disp('test')
            if app.CheckBoxStates{app.CurrentNode(1)}{app.CurrentNode(2)}{1}==0
                warndlg('no Event Data!')
            else
                app.IsSelected{app.CurrentNode(1)}{app.CurrentNode(2)}=1-app.IsSelected{app.CurrentNode(1)}{app.CurrentNode(2)};
                
                if app.IsSelected{app.CurrentNode(1)}{app.CurrentNode(2)}==1
                    app.IsSelectedLabel.Text="SELECTED";
                    t=app.SessionNodes{app.CurrentNode(1)}{app.CurrentNode(2)}.Text;
                    app.SessionNodes{app.CurrentNode(1)}{app.CurrentNode(2)}.Text=[t '√'];
                else
                    app.IsSelectedLabel.Text="";
                    t=app.SessionNodes{app.CurrentNode(1)}{app.CurrentNode(2)}.Text;
                    app.SessionNodes{app.CurrentNode(1)}{app.CurrentNode(2)}.Text=t(1:end-1);
                end
            end
        end

        % Button pushed function: MergeButton
        function MergeButtonPushed(app, event)
            sum_for_progressbar=0;
            for i=1:length(app.IsSelected)
                for j=1:length(app.IsSelected{i})
                    if app.IsSelected{i}{j}==1
                        sum_for_progressbar=sum_for_progressbar+1;
                    end
                end
            end
            count_for_progressbar=0;
            total_wait=waitbar(0,['Session ',num2str(count_for_progressbar),' for ',num2str(sum_for_progressbar)]);
            for iiiii=1:length(app.IsSelected)
                for jjjjj=1:length(app.IsSelected{iiiii})
                    if app.IsSelected{iiiii}{jjjjj}==1
                        count_for_progressbar=count_for_progressbar+1;
                        
                        path=[app.SessionNodes{iiiii}{jjjjj}.NodeData '\']
                        args=app.CheckBoxStates{iiiii}{jjjjj};
                        infor=app.OtherInformation{iiiii}{jjjjj};
%                         [Data,Behavior,Meta,Video,Online,Offline,ST]=mergeButton(path,args);
                        [Data,Behavior,Meta,Video,Online,Offline,ST]=mergeButton_openephys(path,args,infor);
                        if strcmp(args{7},'n')
                            t='';
                            for ii=1:6; t=[t num2str(args{ii})]; end
                            save([path,ST,'BODATA',t,'.mat'],"Data");
                        else
                            %% some bugs here
                            tData=load([path,ST,'BODATA',args{7},'.mat']);
                            tData=tData.Data;
                            t='';
                            for ii=1:6
                                if args{ii}
                                    t=[t '1'];
                                else
                                    t=[t args{7}(ii)];
                                end
                            end
                            tData.Meta=Meta;
                            if args{2}==1 && args{3}==1
                                tData.UnitsOnline=Online;
                                tData.UnitsOffline=Offline;
                            end
                            if args{5}==1
                                tData.Behavior=Behavior;
                            end
                            if args{6}==1
                                tData.Video=Video;
                            end
                            Data=tData;
                            
                            delete([path,ST,'BODATA',args{7},'.mat'])
                            save([path,ST,'BODATA',t,'.mat'],"Data");
                        end
                        
                        str=['Session ',num2str(count_for_progressbar),' for ',num2str(sum_for_progressbar)];
                        waitbar(count_for_progressbar/sum_for_progressbar,total_wait,str);
                    end
                end
            end
            close(total_wait)
            msgbox([num2str(count_for_progressbar) ' merges DONE'])
        end

        % Value changed function: EventsCheckBox
        function EventsCheckBoxValueChanged(app, event)
            app.EventsCheckBox.Value=1;
        end

        % Value changed function: OnlineSpikesCheckBox
        function OnlineSpikesCheckBoxValueChanged(app, event)
            value = app.OnlineSpikesCheckBox.Value;
            app.CheckBoxStates{app.CurrentNode(1)}{app.CurrentNode(2)}{2}=value;
        end

        % Value changed function: OfflineSpikesCheckBox
        function OfflineSpikesCheckBoxValueChanged(app, event)
            value = app.OfflineSpikesCheckBox.Value;
            app.CheckBoxStates{app.CurrentNode(1)}{app.CurrentNode(2)}{3}=value;
            if value==1
                app.CheckBoxStates{app.CurrentNode(1)}{app.CurrentNode(2)}{2}=value;
                app.OnlineSpikesCheckBox.Value=value;
            end
        end

        % Value changed function: SUMUCheckBox
        function SUMUCheckBoxValueChanged(app, event)
            value = app.SUMUCheckBox.Value;
            app.CheckBoxStates{app.CurrentNode(1)}{app.CurrentNode(2)}{4}=value;
            if value==1
                app.CheckBoxStates{app.CurrentNode(1)}{app.CurrentNode(2)}{2}=value;
                app.CheckBoxStates{app.CurrentNode(1)}{app.CurrentNode(2)}{3}=value;
                app.OnlineSpikesCheckBox.Value=value;
                app.OfflineSpikesCheckBox.Value=value;
            end
        end

        % Value changed function: AnalogDataCheckBox
        function AnalogDataCheckBoxValueChanged(app, event)
            value = app.AnalogDataCheckBox.Value;
            app.CheckBoxStates{app.CurrentNode(1)}{app.CurrentNode(2)}{5}=value;
        end

        % Value changed function: VideoCheckBox
        function VideoCheckBoxValueChanged(app, event)
            value = app.VideoCheckBox.Value;
            app.CheckBoxStates{app.CurrentNode(1)}{app.CurrentNode(2)}{6}=value;
        end

        % Button pushed function: LoadFuncButton
        function LoadFuncButtonPushed(app, event)
            app.SinglePlotSelected=[];
            app.MultiPlotSelected=[];
            app.SinglePlots.Items={};
            app.MultiPlots.Items={};
            t=dir([pwd '\APPfuncs\plot_funcs\*.m']);
            for i=1:size(t,1)
                if t(i).name(1)=='s'
                    app.SinglePlots.Items{end+1}=t(i).name(3:end-2);
                    app.SinglePlotSelected=[app.SinglePlotSelected 0];
                else
                    app.MultiPlots.Items{end+1}=t(i).name(3:end-2);
                    app.MultiPlotSelected=[app.MultiPlotSelected 0];
                end
            end
        end

        % Button pushed function: SelectSessionButton
        function SelectSessionButtonPushed(app, event)
            t=app.DataList_2.SelectedNodes;
            linshi=isstrprop(t.Text,'digit');
            if length(linshi)<8; linshi=[0 0 0 0 0 0 0 0]; end
            if sum(linshi(1:8))==8
                app.SessionSelected{app.CurrentNode(1)}{app.CurrentNode(2)}=1-app.SessionSelected{app.CurrentNode(1)}{app.CurrentNode(2)};
                if app.SessionSelected{app.CurrentNode(1)}{app.CurrentNode(2)}
                    app.DataList_2.SelectedNodes.Text=[t.Text(1:end) '√'];
                else
                    app.DataList_2.SelectedNodes.Text=t.Text(1:end-1);
                end
            end
        end

        % Button pushed function: SelectSinglePlotButton
        function SelectSinglePlotButtonPushed(app, event)
            t=app.SinglePlots.Items;
            index=0;
            for i=1:length(t)
                if strcmp(app.SinglePlots.Value,t{i})
                    index=i;
                    break;
                end
            end
            app.SinglePlotSelected(index)=1-app.SinglePlotSelected(index);
            if app.SinglePlotSelected(index)
                if ~contains(app.SinglePlots.Items{index},'√')
                    app.SinglePlots.Items{index}=[app.SinglePlots.Items{index} '√'];
                end
            else
                if contains(app.SinglePlots.Items{index},'√')
                    app.SinglePlots.Items{index}=app.SinglePlots.Items{index}(1:end-1);
                end
            end
            app.SinglePlots.Value=app.SinglePlots.Items{index};
            
        end

        % Button pushed function: SelectMultiPlotButton
        function SelectMultiPlotButtonPushed(app, event)
            t=app.MultiPlots.Items;
            index=0;
            for i=1:length(t)
                if strcmp(app.MultiPlots.Value,t{i})
                    index=i;
                    break;
                end
            end
            app.MultiPlotSelected(index)=1-app.MultiPlotSelected(index);
            if app.MultiPlotSelected(index)
                if ~contains(app.MultiPlots.Items{index},'√')
                    app.MultiPlots.Items{index}=[app.MultiPlots.Items{index} '√'];
                end
            else
                if contains(app.MultiPlots.Items{index},'√')
                    app.MultiPlots.Items{index}=app.MultiPlots.Items{index}(1:end-1);
                end
            end
            app.MultiPlots.Value=app.MultiPlots.Items{index};
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            for k=1:length(app.SinglePlotSelected)
                if app.SinglePlotSelected(k)
                    for i=1:length(app.SessionSelected)
                        for j=1:length(app.SessionSelected{i})
                            if app.SessionSelected{i}{j}
                                animalname=app.AnimalNodes{i}.Text
                                sessionname=app.SessionNodes{i}{j}.Text(1:end-1)
                                path=[app.SessionNodes{i}{j}.NodeData '\'];
                                f=dir([path,'*BODATA*.mat']);
                                t=[path, f.name];
                                tData=load([path, f.name]);
                                str=['s_' app.SinglePlots.Items{k}(1:end-1) '(animalname,sessionname,tData.Data)']
                                %                                 try
                                eval(str);
                                %                                 end
                            end
                        end
                    end
                    msgbox(['s-' app.SinglePlots.Items{k}(1:end-1) ' DONE'])
                end

            end
            
            
            data_paths={};
            for i=1:length(app.SessionSelected)
                for j=1:length(app.SessionSelected{i})
                    if app.SessionSelected{i}{j}
                        animalname=app.AnimalNodes{i}.Text;
                        t=[app.SessionNodes{i}{j}.NodeData '\'];
                        f=dir([t,'*BODATA*.mat']);
                        data_paths{end+1}=[t,f.name];
                    end
                end
            end
            
            for k=1:length(app.MultiPlotSelected)
                if app.MultiPlotSelected(k)
                    str=['m_' app.MultiPlots.Items{k}(1:end-1) '(animalname,data_paths)']
                    %                         try
                    eval(str);
                    %                         end
                    msgbox(['m-' app.MultiPlots.Items{k}(1:end-1) ' DONE'])
                end
            end
        end

        % Callback function
        function AnalysisButtonPushed(app, event)
            t=app.SessionNodes
            path=[app.SessionNodes{app.CurrentNode(1)}{app.CurrentNode(2)}.NodeData '\'];
            f=dir([path,'*BODATA*.mat']);
            t=[path, f.name];
            tData=load([path, f.name]).Data;
            AllFrameTimesInB=tData.Behavior.EventTimings(tData.Behavior.EventMarkers==find(tData.Behavior.Labels=="Frame"));
            plot(app.BlackrockFrames,1:length(AllFrameTimesInB)-1,diff(AllFrameTimesInB));
            ylim(app.BlackrockFrames,[0 100]);
            
            VideoIndex=[]; VideoTimesInB=[];
            count=0; tflag=0; flag_record=0;
            tt=[];
            
            for i=1:length(AllFrameTimesInB)
                if ~tflag && sum(AllFrameTimesInB>AllFrameTimesInB(i) & AllFrameTimesInB<AllFrameTimesInB(i)+80000)>3980 %at least record 80s 3980frames
                    tflag=1;
                    flag_record=flag_record+44999;
                end
                if tflag
                    count=count+1;
                    if count<=flag_record
                        VideoIndex=[VideoIndex tData.Video.SideAviIndex(count)];
                        VideoTimesInB=[VideoTimesInB AllFrameTimesInB(i)];
                    else
                        count=count-1;
                    end
                end
                if i<length(AllFrameTimesInB) && AllFrameTimesInB(i+1)-AllFrameTimesInB(i)>500
                    tflag=0;
                end
            end
            
            
            hold(app.RGBofLED,'off')
            plot(app.RGBofLED,0,0,'w')
            hold(app.RGBofLED,'on')
            LEDInB=tData.Behavior.EventTimings(tData.Behavior.EventMarkers==find(tData.Behavior.Labels=="FlashOnset"));
            ttt=[];
            for i=1:length(LEDInB)
                t=find(abs(VideoTimesInB-LEDInB(i))==min(abs(VideoTimesInB-LEDInB(i))));
                if length(t)>1; t=t(1); end
                if VideoTimesInB(t)<LEDInB(i); t=t+1; end
                if ~isempty(t) && t<44997
                    tmin=max(1,t-3); tmax=min(44999,t+3);
                    plot(app.RGBofLED,VideoTimesInB(tmin:tmax)-VideoTimesInB(t),tData.Video.LEDRGBinVideo(tmin:tmax),'k')
                    ttt=[ttt VideoTimesInB(t)-LEDInB(i)];
                end
            end
            xlim(app.RGBofLED,[-80 80])
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            %             app.DataPath=uigetdir("F:\lab\volitional-BCI\Data");
            
            %% initialize
            app.clear_all();
            
            
            %% search for all sessions and prepare nodes
            animal_list=dir(app.DataPath);
            for i=3:size(animal_list,1)
                app.AnimalNodes{end+1}=uitreenode(app.DataList);
                app.AnimalNodes{end}.Text=animal_list(i).name;
                
                session_list=dir([app.DataPath,'\',animal_list(i).name]);
                t={session_list.name}
                t={}; tt={}; ttt={}; tttt={};
                for j=3:size(session_list,1)
                    linshi=isstrprop(session_list(j).name,'digit');
                    if length(linshi)<8; linshi=[0 0 0 0 0 0 0 0]; end
                    if sum(linshi(1:8))==8
                        tt{end+1}=0;
                        t{end+1}=uitreenode(app.AnimalNodes{end});
                        t{end}.Text=session_list(j).name;
                        t{end}.NodeData=[app.DataPath,'\',app.AnimalNodes{end}.Text,'\',session_list(j).name];
                        t{end}.Tag=[num2str(i-2) ' ' num2str(j-2)];
                        ttt{end+1}=app.get_rawdata_info(t{end});
                        tttt{end+1}=struct();
                    end
                end
                app.SessionNodes{end+1}=t;
                app.IsSelected{end+1}=tt;
                app.CheckBoxStates{end+1}=ttt;
                app.ContainsRawData{end+1}=ttt;
                app.OtherInformation{end+1}=tttt;
            end
            
        end

        % Button pushed function: LoadDataButton_2
        function LoadDataButton_2Pushed(app, event)
            app.clear_all();
            
            %% search for all sessions and prepare nodes
            animal_list=dir(app.DataPath);
            for i=3:size(animal_list,1)
                app.AnimalNodes{end+1}=uitreenode(app.DataList_2);
                app.AnimalNodes{end}.Text=animal_list(i).name;
                
                session_list=dir([app.DataPath,'\',animal_list(i).name]);
                t={}; tt={}; ttt={};
                for j=3:size(session_list,1)
                    linshi=isstrprop(session_list(j).name,'digit');
                    if length(linshi)<8; linshi=[0 0 0 0 0 0 0 0]; end
                    if sum(linshi(1:8))==8
                        tt{end+1}=0;
                        t{end+1}=uitreenode(app.AnimalNodes{end});
                        t{end}.Text=session_list(j).name;
                        t{end}.NodeData=[app.DataPath,'\',app.AnimalNodes{end}.Text,'\',session_list(j).name];
                        t{end}.Tag=[num2str(i-2) ' ' num2str(j-2)];
                        ttt{end+1}=app.get_rawdata_info(t{end});
                        if strcmp(ttt{end}{7},'n')
                            t{end}.Text='########';
                        end
                    end
                end
                app.SessionNodes{end+1}=t;
                app.SessionSelected{end+1}=tt;
                app.CheckBoxStates{end+1}=ttt;
            end
        end

        % Button pushed function: LoadDataButton_3
        function LoadDataButton_3Pushed(app, event)
            app.clear_all();
            
            %% search for all sessions and prepare nodes
            animal_list=dir(app.DataPath);
            for i=3:size(animal_list,1)
                app.AnimalNodes{end+1}=uitreenode(app.DataList_3);
                app.AnimalNodes{end}.Text=animal_list(i).name;
                
                session_list=dir([app.DataPath,'\',animal_list(i).name]);
                t={}; tt={}; ttt={};
                for j=3:size(session_list,1)
                    linshi=isstrprop(session_list(j).name,'digit');
                    if length(linshi)<8; linshi=[0 0 0 0 0 0 0 0]; end
                    if sum(linshi(1:8))==8
                        tt{end+1}=0;
                        t{end+1}=uitreenode(app.AnimalNodes{end});
                        t{end}.Text=session_list(j).name;
                        t{end}.NodeData=[app.DataPath,'\',app.AnimalNodes{end}.Text,'\',session_list(j).name];
                        t{end}.Tag=[num2str(i-2) ' ' num2str(j-2)];
                        ttt{end+1}=app.get_rawdata_info(t{end});
                        if strcmp(ttt{end}{7},'n')
                            t{end}.Text='########';
                        elseif ttt{end}{7}(end)=='0'
                            t{end}.Text='########';
                        end
                    end
                end
                app.SessionNodes{end+1}=t;
                app.VideoSelected{end+1}=tt;
                app.CheckBoxStates{end+1}=ttt;
            end
        end

        % Selection changed function: DataList
        function DataListSelectionChanged(app, event)
            selectedNode = app.DataList.SelectedNodes;
            linshi=isstrprop(selectedNode.Text,'digit');
            if length(linshi)<8; linshi=[0 0 0 0 0 0 0 0]; end
            if sum(linshi(1:8))==8
                t=selectedNode.Tag;
                t=split(t);
                app.CurrentNode=[str2double(t{1}),str2double(t{2})];
                disp(app.CurrentNode)
                state_checkbox=app.CheckBoxStates{app.CurrentNode(1)}{app.CurrentNode(2)};
                contains_rawdata=app.ContainsRawData{app.CurrentNode(1)}{app.CurrentNode(2)};
                
                %% renew ui
                for ii=1:6
                    if state_checkbox{ii}==1
                        app.IncludeDataCheckBoxs{ii}.Value=1;
                    else
                        if ii~=1; app.IncludeDataCheckBoxs{ii}.Value=0; end
                    end
                    if contains_rawdata{ii}==1
                        app.IncludeDataCheckBoxs{ii}.Enable=1;
                    else
                        app.IncludeDataCheckBoxs{ii}.Enable=0;
                    end
                end
                
                if strcmp(state_checkbox{end},'n')
                    app.ExistInfoLabel.Text="No file exists";
                    for i=1:6; app.IncludeDataCheckBoxs{i}.FontWeight='normal'; end
                else
                    app.ExistInfoLabel.Text=["The file already exists.";"Selected data part will be overwritten."];
                    for i=1:6
                        if state_checkbox{end}(i)=='1'
                            app.IncludeDataCheckBoxs{i}.FontWeight='bold';
                        else
                            app.IncludeDataCheckBoxs{i}.FontWeight='normal';
                        end
                    end
                end
                
                if app.IsSelected{app.CurrentNode(1)}{app.CurrentNode(2)}==1
                    app.IsSelectedLabel.Text="SELECTED";
                else
                    app.IsSelectedLabel.Text="";
                end
            end
        end

        % Selection changed function: DataList_2
        function DataList_2SelectionChanged(app, event)
            selectedNode= app.DataList_2.SelectedNodes;
            st0=""; st1=""; st2=""; st3=""; st4="";
            linshi=isstrprop(selectedNode.Text,'digit');
            if length(linshi)<8; linshi=[0 0 0 0 0 0 0 0]; end
            if sum(linshi(1:8))==8
                t=selectedNode.Tag;
                t=split(t);
                app.CurrentNode=[str2double(t{1}),str2double(t{2})];
                path=[app.SessionNodes{app.CurrentNode(1)}{app.CurrentNode(2)}.NodeData '\'];
                f=dir([path,'*BODATA*.mat']);
                st0=f.name(1:3);
                if app.CheckBoxStates{str2double(t{1})}{str2double(t{2})}{3}
                    st1="offline";
                end
                if app.CheckBoxStates{str2double(t{1})}{str2double(t{2})}{4}
                    st2="SU/MU";
                end
                if app.CheckBoxStates{str2double(t{1})}{str2double(t{2})}{5}
                    st3="Weight & Position";
                end
                if app.CheckBoxStates{str2double(t{1})}{str2double(t{2})}{6}
                    st4="video";
                end
            end
            app.SessionInfoLabel.Text=[st0;st1;st2;st3;st4];
        end

        % Selection changed function: DataList_3
        function DataList_3SelectionChanged(app, event)
            selectedNode = app.DataList_3.SelectedNodes;
            st={'Info'};
            linshi=isstrprop(selectedNode.Text,'digit');
            if length(linshi)<8; linshi=[0 0 0 0 0 0 0 0]; end
            if sum(linshi(1:8))==8
                t=selectedNode.Tag;
                t=split(t);
                app.CurrentNode=[str2double(t{1}),str2double(t{2})];
                
                t=dir(selectedNode.NodeData);
                
                cams={};
                cam_counts=[];
                for i=3:size(t,1)
                    if contains(t(i).name,'Cam')
                        tt=t(i).name(11:15);
                        if isempty(cams)
                            cams{end+1}=tt;
                            cam_counts=[cam_counts 1];
                        else
                            flag=0;
                            for j=1:length(cams)
                                if strcmp(cams{j},tt)
                                    cam_counts(j)=cam_counts(j)+1;
                                    flag=1;
                                    break;
                                end
                            end
                            if ~flag
                                cams{end+1}=tt;
                                cam_counts=[cam_counts 1];
                            end
                        end
                    end
                end
                
                for i=1:length(cam_counts)
                    st{end}=[num2str(cam_counts(i)/2),' x cam',cams{i}];
                    st{end+1}='';
                end
                
            end
            app.VideoInfoLabel.Text=st;
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            app.clear_all();
        end

        % Button pushed function: OtherInformationButton
        function OtherInformationButtonPushed(app, event)
            app.OtherInformation_app=other_information(app);
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
            if key=='z'
                if strcmp(app.TabGroup.SelectedTab.Title,'Merge Data')
                    app.SelectButtonPushed(event);
                elseif strcmp(app.TabGroup.SelectedTab.Title,'Plot Figures')
                    disp('z')
                    app.SelectSessionButtonPushed(event);
                end
            end
        end

        % Button pushed function: AnalysisButton
        function AnalysisButtonPushed2(app, event)
            t=app.SessionNodes
            path=[app.SessionNodes{app.CurrentNode(1)}{app.CurrentNode(2)}.NodeData '\'];
            f=dir([path,'*BODATA*.mat']);
            t=[path, f.name];
            tData=load([path, f.name]).Data;
            AllFrameTimesInB=tData.Behavior.EventTimings(tData.Behavior.EventMarkers==find(tData.Behavior.Labels=="Frame"));
            plot(app.BlackrockFrames,1:length(AllFrameTimesInB)-1,diff(AllFrameTimesInB));
            ylim(app.BlackrockFrames,[0 100]);
            
            VideoIndex=[]; VideoTimesInB=[];
            count=0; tflag=0; flag_record=0;
            tt=[];
            
            for i=1:length(AllFrameTimesInB)
                if ~tflag && sum(AllFrameTimesInB>AllFrameTimesInB(i) & AllFrameTimesInB<AllFrameTimesInB(i)+80000)>3980 %at least record 80s 3980frames
                    tflag=1;
                    flag_record=flag_record+44999;
                end
                if tflag
                    count=count+1;
                    if count<=flag_record
                        VideoIndex=[VideoIndex tData.Video.SideAviIndex(count)];
                        VideoTimesInB=[VideoTimesInB AllFrameTimesInB(i)];
                    else
                        count=count-1;
                    end
                end
                if i<length(AllFrameTimesInB) && AllFrameTimesInB(i+1)-AllFrameTimesInB(i)>500
                    tflag=0;
                end
            end
            
            
            hold(app.RGBofLED,'off')
            plot(app.RGBofLED,0,0,'w')
            hold(app.RGBofLED,'on')
            LEDInB=tData.Behavior.EventTimings(tData.Behavior.EventMarkers==find(tData.Behavior.Labels=="FlashOnset"));
            ttt=[];
            for i=1:length(LEDInB)
                t=find(abs(VideoTimesInB-LEDInB(i))==min(abs(VideoTimesInB-LEDInB(i))));
                if length(t)>1; t=t(1); end
                if VideoTimesInB(t)<LEDInB(i); t=t+1; end
                if ~isempty(t) && t<44997
                    tmin=max(1,t-3); tmax=min(44999,t+3);
                    plot(app.RGBofLED,VideoTimesInB(tmin:tmax)-VideoTimesInB(t),tData.Video.LEDRGBinVideo(tmin:tmax),'k')
                    ttt=[ttt VideoTimesInB(t)-LEDInB(i)];
                end
            end
            xlim(app.RGBofLED,[-80 80])
        end

        % Button pushed function: SelectVideoButton
        function SelectVideoButtonPushed(app, event)
            t=app.DataList_3.SelectedNodes;
            linshi=isstrprop(t.Text,'digit');
            if length(linshi)<8; linshi=[0 0 0 0 0 0 0 0]; end
            if sum(linshi(1:8))==8
                app.VideoSelected{app.CurrentNode(1)}{app.CurrentNode(2)}=1-app.VideoSelected{app.CurrentNode(1)}{app.CurrentNode(2)};
                if app.VideoSelected{app.CurrentNode(1)}{app.CurrentNode(2)}
                    app.DataList_3.SelectedNodes.Text=[t.Text(1:end) '√'];
                else
                    app.DataList_3.SelectedNodes.Text=t.Text(1:end-1);
                end
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            for i=1:length(app.VideoSelected)
                for j=1:length(app.VideoSelected{i})
                    if app.VideoSelected{i}{j}
                        animalname=app.AnimalNodes{i}.Text
                        sessionname=app.SessionNodes{i}{j}.Text(1:end-1)
                        path=[app.SessionNodes{i}{j}.NodeData '\'];
                        f=dir([path,'*BODATA*.mat']);
                        t=[path, f.name];
                        tData=load([path, f.name]);
                        str='run_video(animalname,sessionname,tData.Data,app.DataPath)'
                        %                                 try
                        eval(str);
                        %                                 end
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.MergeDataTab = uitab(app.TabGroup);
            app.MergeDataTab.Title = 'Merge Data';
            app.GridLayout = uigridlayout(app.MergeDataTab);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.AnimalSessionsLabel = uilabel(app.GridLayout2);
            app.AnimalSessionsLabel.Text = 'Animal & Sessions';
            app.DataList = uitree(app.GridLayout2);
            app.DataList.SelectionChangedFcn = createCallbackFcn(app, @DataListSelectionChanged, true);
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.LoadDataButton = uibutton(app.GridLayout3, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.SettingsButton = uibutton(app.GridLayout3, 'push');
            app.SettingsButton.Text = 'Settings';
            app.MergeButton = uibutton(app.GridLayout3, 'push');
            app.MergeButton.ButtonPushedFcn = createCallbackFcn(app, @MergeButtonPushed, true);
            app.MergeButton.Text = 'Merge';
            app.SelectButton = uibutton(app.GridLayout3, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.OtherInformationButton = uibutton(app.GridLayout3, 'push');
            app.OtherInformationButton.ButtonPushedFcn = createCallbackFcn(app, @OtherInformationButtonPushed, true);
            app.OtherInformationButton.Text = 'Other Information';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.IncludeDataLabel = uilabel(app.GridLayout4);
            app.IncludeDataLabel.Text = 'Include Data';
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.EventsCheckBox = uicheckbox(app.GridLayout5);
            app.EventsCheckBox.ValueChangedFcn = createCallbackFcn(app, @EventsCheckBoxValueChanged, true);
            app.EventsCheckBox.Text = 'Events';
            app.EventsCheckBox.Value = true;
            app.OnlineSpikesCheckBox = uicheckbox(app.GridLayout5);
            app.OnlineSpikesCheckBox.ValueChangedFcn = createCallbackFcn(app, @OnlineSpikesCheckBoxValueChanged, true);
            app.OnlineSpikesCheckBox.Text = 'Online Spikes';
            app.OfflineSpikesCheckBox = uicheckbox(app.GridLayout5);
            app.OfflineSpikesCheckBox.ValueChangedFcn = createCallbackFcn(app, @OfflineSpikesCheckBoxValueChanged, true);
            app.OfflineSpikesCheckBox.Text = 'Offline Spikes';
            app.AnalogDataCheckBox = uicheckbox(app.GridLayout5);
            app.AnalogDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnalogDataCheckBoxValueChanged, true);
            app.AnalogDataCheckBox.Text = 'Analog Data';
            app.VideoCheckBox = uicheckbox(app.GridLayout5);
            app.VideoCheckBox.ValueChangedFcn = createCallbackFcn(app, @VideoCheckBoxValueChanged, true);
            app.VideoCheckBox.Text = 'Videos';
            app.ExistInfoLabel = uilabel(app.GridLayout5);
            app.ExistInfoLabel.Text = {'The file already exists. '; 'Selected data part will be overwritten.'};
            app.HelpLabel = uilabel(app.GridLayout5);
            app.HelpLabel.Text = {'Bold:       Already Included'; 'Enabled: Raw Data Exists'; 'Checked:Data Part to Merge'};
            app.IsSelectedLabel = uilabel(app.GridLayout5);
            app.IsSelectedLabel.Text = {''; ''};
            app.SUMUCheckBox = uicheckbox(app.GridLayout5);
            app.SUMUCheckBox.ValueChangedFcn = createCallbackFcn(app, @SUMUCheckBoxValueChanged, true);
            app.SUMUCheckBox.Text = 'SU/MU';
            app.PlotFiguresTab = uitab(app.TabGroup);
            app.PlotFiguresTab.Title = 'Plot Figures';
            app.GridLayout6 = uigridlayout(app.PlotFiguresTab);
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.AnimalSessionsLabel_2 = uilabel(app.GridLayout7);
            app.AnimalSessionsLabel_2.Text = 'Animal & Sessions';
            app.DataList_2 = uitree(app.GridLayout7);
            app.DataList_2.SelectionChangedFcn = createCallbackFcn(app, @DataList_2SelectionChanged, true);
            app.GridLayout8 = uigridlayout(app.GridLayout6);
            app.SingleSessionPlotLabel = uilabel(app.GridLayout8);
            app.SingleSessionPlotLabel.Text = 'Single Session Plot';
            app.MultiSessionsPlot1animalpertimeLabel = uilabel(app.GridLayout8);
            app.MultiSessionsPlot1animalpertimeLabel.Text = {'Multi Sessions Plot'; '1 animal per time'};
            app.SinglePlots = uilistbox(app.GridLayout8);
            app.SinglePlots.Items = {};
            app.SinglePlots.Value = {};
            app.MultiPlots = uilistbox(app.GridLayout8);
            app.MultiPlots.Items = {};
            app.MultiPlots.Value = {};
            app.GridLayout9 = uigridlayout(app.GridLayout6);
            app.LoadDataButton_2 = uibutton(app.GridLayout9, 'push');
            app.LoadDataButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButton_2Pushed, true);
            app.LoadDataButton_2.Text = 'Load Data';
            app.SelectSessionButton = uibutton(app.GridLayout9, 'push');
            app.SelectSessionButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSessionButtonPushed, true);
            app.SelectSessionButton.Text = 'Select Session';
            app.SelectSinglePlotButton = uibutton(app.GridLayout9, 'push');
            app.SelectSinglePlotButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSinglePlotButtonPushed, true);
            app.SelectSinglePlotButton.Text = 'Select Single Plot';
            app.SelectMultiPlotButton = uibutton(app.GridLayout9, 'push');
            app.SelectMultiPlotButton.ButtonPushedFcn = createCallbackFcn(app, @SelectMultiPlotButtonPushed, true);
            app.SelectMultiPlotButton.Text = 'Select Multi Plot';
            app.PlotButton = uibutton(app.GridLayout9, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.LoadFuncButton = uibutton(app.GridLayout9, 'push');
            app.LoadFuncButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFuncButtonPushed, true);
            app.LoadFuncButton.Text = 'Load Func';
            app.SettingsButton_2 = uibutton(app.GridLayout9, 'push');
            app.SettingsButton_2.Text = 'Settings';
            app.SessionInfoLabel = uilabel(app.GridLayout9);
            app.SessionInfoLabel.Text = 'Info';
            app.VideoClippingTab = uitab(app.TabGroup);
            app.VideoClippingTab.Title = 'Video Clipping';
            app.GridLayout10 = uigridlayout(app.VideoClippingTab);
            app.GridLayout11 = uigridlayout(app.GridLayout10);
            app.AnimalSessionsLabel_3 = uilabel(app.GridLayout11);
            app.AnimalSessionsLabel_3.Text = 'Animal & Sessions';
            app.DataList_3 = uitree(app.GridLayout11);
            app.DataList_3.SelectionChangedFcn = createCallbackFcn(app, @DataList_3SelectionChanged, true);
            app.GridLayout12 = uigridlayout(app.GridLayout10);
            app.BlackrockFrames = uiaxes(app.GridLayout12);
            title(app.BlackrockFrames, 'BFrameTime(i)-BFrameTime(i-1)')
            xlabel(app.BlackrockFrames, '')
            ylabel(app.BlackrockFrames, '')
            app.BlackrockFrames.YLim = [0 100];
            app.BlackrockFrames.XTick = [0 1];
            app.BlackrockFrames.YTick = [];
            app.BlackrockFrames.YTickLabel = '';
            app.RGBofLED = uiaxes(app.GridLayout12);
            title(app.RGBofLED, 'RGBofLed')
            xlabel(app.RGBofLED, '')
            ylabel(app.RGBofLED, '')
            app.RGBofLED.YLim = [0 255];
            app.RGBofLED.XTick = [0 1];
            app.RGBofLED.YTick = [];
            app.GridLayout13 = uigridlayout(app.GridLayout10);
            app.VideoInfoLabel = uilabel(app.GridLayout13);
            app.VideoInfoLabel.Text = 'Info';
            app.LoadDataButton_3 = uibutton(app.GridLayout13, 'push');
            app.LoadDataButton_3.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButton_3Pushed, true);
            app.LoadDataButton_3.Text = 'Load Data';
            app.AnalysisButton = uibutton(app.GridLayout13, 'push');
            app.AnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysisButtonPushed2, true);
            app.AnalysisButton.Text = 'Analysis';
            app.RunButton = uibutton(app.GridLayout13, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.SelectVideoButton = uibutton(app.GridLayout13, 'push');
            app.SelectVideoButton.ButtonPushedFcn = createCallbackFcn(app, @SelectVideoButtonPushed, true);
            app.SelectVideoButton.Text = 'Select Session';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = data_analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end