classdef other_information < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        DrugsPanel                   matlab.ui.container.Panel
        GridLayout                   matlab.ui.container.GridLayout
        DrugDropDownLabel            matlab.ui.control.Label
        DrugDropDown                 matlab.ui.control.DropDown
        DoseDropDownLabel            matlab.ui.control.Label
        DoseDropDown                 matlab.ui.control.DropDown
        InjectiontimeEditFieldLabel  matlab.ui.control.Label
        InjectiontimeEditField       matlab.ui.control.NumericEditField
        StateDropDownLabel           matlab.ui.control.Label
        StateDropDown                matlab.ui.control.DropDown
        CommentsPanel                matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        Comments                     matlab.ui.control.EditField
        Panel_2                      matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        ApplyButton                  matlab.ui.control.Button
        ResetButton                  matlab.ui.control.Button
        CancelButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        main_app
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
           app.main_app=mainapp;
        end

        % Value changed function: DrugDropDown
        function DrugDropDownValueChanged(app, event)
            value = app.DrugDropDown.Value;
            if strcmp(value,'None')
                app.DoseDropDown.Enable=0;
                app.DoseDropDown.Value='0.25x';
                app.InjectiontimeEditField.Value=0;
                app.InjectiontimeEditField.Enable=0;
                app.StateDropDown.Value='pre-Drug';
                app.StateDropDown.Enable=0;
            else
                app.DoseDropDown.Enable=1;
                app.InjectiontimeEditField.Enable=1;
                app.StateDropDown.Enable=1;
                app.InjectiontimeEditField.Value=15;
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            information=struct();
            information.Drug=app.DrugDropDown.Value;
            information.Dose=app.DoseDropDown.Value;
            information.InjectionTime=app.InjectiontimeEditField.Value;
            information.State=app.StateDropDown.Value;
            information.Comments=app.Comments.Value;
            
            app.main_app.update_OtherInformation(information);
            delete(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.DrugDropDown.Value='None';
            app.DoseDropDown.Value='0.25x';
            app.InjectiontimeEditField.Value=0;
            app.StateDropDown.Value='pre-Drug';
            app.DrugDropDownValueChanged();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DrugsPanel = uipanel(app.UIFigure);
            app.DrugsPanel.Title = 'Drugs';
            app.GridLayout = uigridlayout(app.DrugsPanel);
            app.DrugDropDownLabel = uilabel(app.GridLayout);
            app.DrugDropDownLabel.Text = 'Drug';
            app.DrugDropDown = uidropdown(app.GridLayout);
            app.DrugDropDown.Items = {'None', 'Saline', 'DCZ'};
            app.DrugDropDown.ValueChangedFcn = createCallbackFcn(app, @DrugDropDownValueChanged, true);
            app.DrugDropDown.Value = 'None';
            app.DoseDropDownLabel = uilabel(app.GridLayout);
            app.DoseDropDownLabel.Enable = 'off';
            app.DoseDropDownLabel.Text = 'Dose';
            app.DoseDropDown = uidropdown(app.GridLayout);
            app.DoseDropDown.Items = {'0.25x', '0.5x', '1x', '2x'};
            app.DoseDropDown.Editable = 'on';
            app.DoseDropDown.Enable = 'off';
            app.DoseDropDown.Value = '0.25x';
            app.InjectiontimeEditFieldLabel = uilabel(app.GridLayout);
            app.InjectiontimeEditFieldLabel.Enable = 'off';
            app.InjectiontimeEditFieldLabel.Text = 'Injection time';
            app.InjectiontimeEditField = uieditfield(app.GridLayout, 'numeric');
            app.InjectiontimeEditField.Limits = [-120 120];
            app.InjectiontimeEditField.RoundFractionalValues = 'on';
            app.InjectiontimeEditField.ValueDisplayFormat = '%d min';
            app.InjectiontimeEditField.Enable = 'off';
            app.InjectiontimeEditField.Value = 15;
            app.StateDropDownLabel = uilabel(app.GridLayout);
            app.StateDropDownLabel.Enable = 'off';
            app.StateDropDownLabel.Text = 'State';
            app.StateDropDown = uidropdown(app.GridLayout);
            app.StateDropDown.Items = {'pre-Drug', 'post-Drug'};
            app.StateDropDown.Enable = 'off';
            app.StateDropDown.Value = 'pre-Drug';
            app.CommentsPanel = uipanel(app.UIFigure);
            app.CommentsPanel.Title = 'Comments';
            app.GridLayout2 = uigridlayout(app.CommentsPanel);
            app.Comments = uieditfield(app.GridLayout2, 'text');
            app.Comments.Value = ' ';
            app.Panel_2 = uipanel(app.UIFigure);
            app.GridLayout3 = uigridlayout(app.Panel_2);
            app.ApplyButton = uibutton(app.GridLayout3, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.ResetButton = uibutton(app.GridLayout3, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.CancelButton = uibutton(app.GridLayout3, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = other_information(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end